classdef w2mhs < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        File                            matlab.ui.container.Menu
        SaveSettingsMenu                matlab.ui.container.Menu
        OpenSettingsMenu                matlab.ui.container.Menu
        SetasDefaultMenu                matlab.ui.container.Menu
        ResettoDefaultMenu              matlab.ui.container.Menu
        SaveBeforeExitMenu              matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        MainTab                         matlab.ui.container.Tab
        SPM12PathField                  matlab.ui.control.EditField
        SPM12PathButton                 matlab.ui.control.Button
        OutputPathField                 matlab.ui.control.EditField
        OutputPathButton                matlab.ui.control.Button
        SubjectsListBox                 matlab.ui.control.ListBox
        AddImagesButton                 matlab.ui.control.Button
        RemoveButton                    matlab.ui.control.Button
        ClearButton                     matlab.ui.control.Button
        MoveUpButton                    matlab.ui.control.Button
        MoveDownButton                  matlab.ui.control.Button
        OptionsTab                      matlab.ui.container.Tab
        ProceduresPanel                 matlab.ui.container.Panel
        VisualizationSwitchLabel        matlab.ui.control.Label
        VisualSwitch                    matlab.ui.control.Switch
        PreprocSwitch                   matlab.ui.control.Switch
        PreprocessingSwitch_2Label      matlab.ui.control.Label
        HyperparametersPanel            matlab.ui.container.Panel
        ClassificationBatchSizeSpinnerLabel  matlab.ui.control.Label
        BatchSizeSpinner                matlab.ui.control.Spinner
        ProbabilityMapCutoffLabel       matlab.ui.control.Label
        PMapCutSpinner                  matlab.ui.control.Spinner
        GrayMatterCleaningDistanceSpinnerLabel  matlab.ui.control.Label
        CleanThSpinner                  matlab.ui.control.Spinner
        PeriventricularRegionWidthLabel  matlab.ui.control.Label
        VentDilSpinner                  matlab.ui.control.Spinner
        HelperArea                      matlab.ui.control.TextArea
        InputImageTypeIdentifiersPanel  matlab.ui.container.Panel
        T1Label                         matlab.ui.control.Label
        T1Field                         matlab.ui.control.EditField
        T2EditFieldLabel                matlab.ui.control.Label
        T2Field                         matlab.ui.control.EditField
        OutputsTab                      matlab.ui.container.Tab
        OutputTable                     matlab.ui.control.Table
        VisualsTab                      matlab.ui.container.Tab
        QuickNIfTIImageViewerPathGeneratorPanel  matlab.ui.container.Panel
        ImageDropDownLabel              matlab.ui.control.Label
        ImageDropDown                   matlab.ui.control.DropDown
        ViewButton                      matlab.ui.control.Button
        SubjectIdEditFieldLabel         matlab.ui.control.Label
        SubjectIdField                  matlab.ui.control.EditField
        FullImagePathEditFieldLabel     matlab.ui.control.Label
        FullImagePathField              matlab.ui.control.EditField
        GenCopyButton                   matlab.ui.control.Button
        AdvancedNIfTIImageViewerPanel   matlab.ui.container.Panel
        UnderlayPathField               matlab.ui.control.EditField
        OverlayPathField                matlab.ui.control.EditField
        ViewButton_2                    matlab.ui.control.Button
        UseOverlayCheckBox              matlab.ui.control.CheckBox
        DualDisplayCheckBox             matlab.ui.control.CheckBox
        UnderlayPathButton              matlab.ui.control.Button
        OverlayPathButton               matlab.ui.control.Button
        BrainImage                      matlab.ui.control.Image
        WiscImage                       matlab.ui.control.Image
        ShadowLabel                     matlab.ui.control.Label
        TitleLabel                      matlab.ui.control.Label
        RunButton                       matlab.ui.control.Button
        VersionLabel                    matlab.ui.control.Label
    end

    
    properties (Access = private)
        
        Subjects  % Subject instance list
    end
    
    methods (Access = private)
        
        %% Initializes the entries of the image visualization dropdown menu
        function ImageDropDownInit(app)
            t = table2struct(app.OutputTable.Data);  % Name table
            app.ImageDropDown.Items = {};
            app.ImageDropDown.ItemsData = {};
            for i = 1:numel(t)  
                if t(i).Keep && strcmpi(t(i).Ext, "nii")
                    app.ImageDropDown.Items{end+1} = t(i).Name;
                    app.ImageDropDown.ItemsData{end+1} = t(i).Filename;
                end
            end
        end
        
        function settings = saveSettings(app, path)
            settings = {};
            settings.spm_path     = app.SPM12PathField.Value;
            settings.output_path  = app.OutputPathField.Value;
            settings.t1_id        = app.T1Field.Value;
            settings.t2_id        = app.T2Field.Value;
            settings.do_preproc   = app.PreprocSwitch.Value;
            settings.do_visual    = app.VisualSwitch.Value;
            settings.batch_size   = app.BatchSizeSpinner.Value;
            settings.pmap_cut     = app.PMapCutSpinner.Value;
            settings.clean_th     = app.CleanThSpinner.Value;
            settings.vent_dil_r   = app.VentDilSpinner.Value;
            settings.name_table   = app.OutputTable.Data;
            settings.subjects     = app.Subjects;
            settings.subject_list = app.SubjectsListBox.Items;
            save(path, 'settings')
        end
        
        function loadSettings(app, path)
            load(path, 'settings');
            app.SPM12PathField.Value   = settings.spm_path;    
            app.OutputPathField.Value  = settings.output_path;
            app.T1Field.Value          = settings.t1_id;
            app.T2Field.Value          = settings.t2_id;
            app.PreprocSwitch.Value    = settings.do_preproc;  
            app.VisualSwitch.Value     = settings.do_visual;   
            app.BatchSizeSpinner.Value = settings.batch_size;
            app.PMapCutSpinner.Value   = settings.pmap_cut;    
            app.CleanThSpinner.Value   = settings.clean_th;   
            app.VentDilSpinner.Value   = settings.vent_dil_r;
            app.OutputTable.Data       = settings.name_table;
            app.Subjects               = settings.subjects;
            app.SubjectsListBox.Items  = settings.subject_list;
            app.SubjectsListBox.ItemsData = 1:numel(app.Subjects);
            app.ImageDropDownInit();
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% Adds toolbox folder and subfolders to the search directory
            installW2MHS
            
            %% Loads default settings
            app.ResettoDefaultMenuSelected()
            
            %% Searches for SPM12 in MATLAB search path if not set by default
            if isempty(app.SPM12PathField.Value)
                % Splits MATLAB search path by separator for current platform
                paths = split(path, pathsep);
                % Finds the first path ending with 'spm12'
                spm_idx = find(cellfun(@(str) strcmpi(str(max(1,end-4):end), 'spm12'), paths), 1);
                % If found, uses the path as the default spm path
                if ~isempty(spm_idx), app.SPM12PathField.Value = paths{spm_idx}; end
            end
            
            %% Initializes subjects
            app.Subjects = {};
        end

        % Button pushed function: SPM12PathButton
        function SPM12PathButtonPushed(app, event)
            path = uigetdir(); if path ~= 0, app.SPM12PathField.Value = path; end
        end

        % Button pushed function: OutputPathButton
        function OutputPathButtonPushed(app, event)
            path = uigetdir(); if path ~= 0, app.OutputPathField.Value = path; end
        end

        % Button pushed function: AddImagesButton
        function AddImagesButtonPushed(app, event)
            %% Picks input nii images or directories using uipickfiles
            picks = uipickfiles('type', {'*.nii', 'NIFTI files'}, 'prompt', 'Add NIFTI files or folders', 'out', 'struct');
            % Do nothing if cancelled
            if ~isstruct(picks(1)), return; end
            % Adds all picked images or images under the picked directories
            files = [];
            for i = 1:numel(picks)
                if picks(i).isdir
                    % Adds all nii images under a picked directory recursively
                    files = [files dir(fullfile(picks(i).name, '**', '*.nii'))];
                else
                    % Adds a picked nii image
                    files = [files picks(i)];
                end
            end
            % Modifies the fields of a file structure
            for i = 1:numel(files)
                files(i).path = fullfile(files(i).folder, files(i).name);  % Full path
                [~, files(i).name] = fileparts(lower(files(i).name));  % Filename without extension
            end
            % Image type IDs
            t1id = lower(app.T1Field.Value); t2id = lower(app.T2Field.Value);
            % Finds indices of all T1 images
            t1mask = arrayfun(@(file) contains(lower(file.name), t1id), files);
            % Separates T1 and T2 images
            t1s = files(t1mask); t2s = files(~t1mask);
            %% Pairs T1 and T2 images
            for i = 1:numel(t1s)
                % For each T1 image, looks for a T2 image with the same subject id
                t1 = t1s(i); t2 = t2s(find(arrayfun(@(t2) strcmp(t2.name, strrep(t1.name, t1id, t2id)), t2s), 1));
                if ~isempty(t2)
                    % Removes leading, trailing, and duplicate underscores and the type id from subject id 
                    id = regexprep(regexprep(strrep(t1.name, t1id, ''), '_+', '_'), '(^_|_$)', '');
                    % Creates a new subject instance from subject id and image paths
                    app.Subjects{end+1} = Subject(id, t1.path, t2.path);
                    % Visualizes the added images in the list box
                    app.SubjectsListBox.Items{end+1} = sprintf('ID:%-16s T1:%-22s T2:%-22s', id, t1.name, t2.name);
                    app.SubjectsListBox.ItemsData = 1:numel(app.SubjectsListBox.Items);
                end
            end
            
            
        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(app, event)
            n = app.SubjectsListBox.Value;  % Selected subject
            if isempty(n), return; end
            % Removes the suject instance
            app.Subjects = [app.Subjects(1:n-1) app.Subjects(n+1:end)];
            % Removes the subject from the list box
            app.SubjectsListBox.Items(n) = []; 
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.Subjects = {};
            app.SubjectsListBox.Items = {};
        end

        % Button pushed function: MoveUpButton
        function MoveUpButtonPushed(app, event)
            n = app.SubjectsListBox.Value;  % Selected subject
            if isempty(n) || n == 1, return; end
            % Moves the suject instance up
            app.Subjects(n-1:n) = [app.Subjects(n) app.Subjects(n-1)];
            % Moves the suject up in the list box
            app.SubjectsListBox.Items(n-1:n) = [app.SubjectsListBox.Items(n) app.SubjectsListBox.Items(n-1)];
            % Selects the z subject moved up
            app.SubjectsListBox.Value = n - 1;
        end

        % Button pushed function: MoveDownButton
        function MoveDownButtonPushed(app, event)
            n = app.SubjectsListBox.Value;  % Selected subject
            if isempty(n) || n == numel(app.SubjectsListBox.Items), return; end
            % Moves the suject instance down
            app.Subjects(n:n+1) = [app.Subjects(n+1) app.Subjects(n)];
            % Moves the suject down in the list box
            app.SubjectsListBox.Items(n:n+1) = [app.SubjectsListBox.Items(n+1) app.SubjectsListBox.Items(n)];
            % Selects the subject moved down
            app.SubjectsListBox.Value = n + 1;
        end

        % Value changing function: T1Field
        function T1FieldValueChanging(app, event)
            app.HelperArea.Value = {'Each subject needs a pair of T1 and T2 images, whose filenames differ only by the image type identifier:';
                ''; ['T1: <pre>' event.Value '<suf>.nii']; ['T2: <pre>' app.T2Field.Value '<suf>.nii']; '';
                '<pre><suf> is used as a unique, case-insensitive subject id to match the two images. Different subjects should have different ids. Leading, trailing, and duplicate underlines are removed.'};
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            %% Sets static fields
            Subject.spm_path(app.SPM12PathField.Value);
            Subject.do_preproc(app.PreprocSwitch.Value);
            Subject.do_visual(app.VisualSwitch.Value);
            Subject.batch_size(app.BatchSizeSpinner.Value);
            Subject.pmap_cut(app.PMapCutSpinner.Value);
            Subject.clean_th(app.CleanThSpinner.Value);
            Subject.vent_dil_r(app.VentDilSpinner.Value);
            
            %% Initializes the entries of the image visualization dropdown menu
            app.ImageDropDownInit();
            
            %% Copies filenames and file keeping options into subject instances
            output_folder = app.OutputPathField.Value;  % Root output folder
            t = table2struct(app.OutputTable.Data);  % Name table
            for i = 1:numel(app.Subjects)
                id = app.Subjects{i}.id;
                app.Subjects{i}.names.folder = fullfile(output_folder, id);  % Subject output folder
                for j = 1:numel(t)
                    app.Subjects{i}.keeps.(t(j).Name) = t(j).Keep;  % Whether file j should be kept
                    app.Subjects{i}.names.(t(j).Name) = [t(j).Filename '_' id '.' t(j).Ext];
                end
                app.Subjects{i}.run();
            end
        end

        % Value changing function: T2Field
        function T2FieldValueChanging(app, event)
            app.HelperArea.Value = {'Each subject needs a pair of T1 and T2 images, whose filenames differ only by the image type identifier:';
                ''; ['T1: <pre>' app.T1Field.Value '<suf>.nii']; ['T2: <pre>' event.Value '<suf>.nii']; '';
                '<pre><suf> is used as a unique, case-insensitive subject id to match the two images. Different subjects should have different ids. Leading, trailing, and duplicate underlines are removed.'};
        end

        % Value changing function: BatchSizeSpinner
        function BatchSizeSpinnerValueChanging(app, event)
            value = event.Value; if isa(value, "double"), value = sprintf('%d', value); end
            app.HelperArea.Value = {[value ' voxels are classified together in one iteration. A larger batch size enhances performance at the cost of higher memory consumption. Recommended value: 2048 * available memory in GB']; '(e.g. 4096 if 2GB memory is available).'};
        end

        % Value changing function: PMapCutSpinner
        function PMapCutSpinnerValueChanging(app, event)
            value = event.Value; if isa(value, "double"), value = sprintf('%g', value); end
            app.HelperArea.Value = {['Voxels classified as WMH with a confidence below ' value ' will be excluded.']};
        end

        % Value changing function: CleanThSpinner
        function CleanThSpinnerValueChanging(app, event)
            value = event.Value; if isa(value, "double"), value = sprintf('%g', value); end
            app.HelperArea.Value = {['Voxels classified as WMH within ' value ' voxels from the gray matter surface will be excluded.']};
        end

        % Value changing function: VentDilSpinner
        function VentDilSpinnerValueChanging(app, event)
            value = event.Value; if isa(value, "double"), value = sprintf('%d', value); end
            app.HelperArea.Value = {['The ventricular template is dilated by ' value ' voxels.']};
        end

        % Value changed function: PreprocSwitch
        function PreprocSwitchValueChanged(app, event)
            value = app.PreprocSwitch.Value;
            if strcmpi(value, "yes")
                app.HelperArea.Value = {'Yes. Every subject will be preprocessed.'};
            else
                app.HelperArea.Value = {'No. Previous preprocessing output will be used if spotted in the output folder.'};
            end
        end

        % Value changed function: VisualSwitch
        function VisualSwitchValueChanged(app, event)
            value = app.VisualSwitch.Value;
            if strcmpi(value, "yes")
                app.HelperArea.Value = {'Yes. Once a subject finishes, two NIfTI image visualizers will pop up: one with an overlaying WMH (both deep and periventricular) heatmap and the other without, sharing their crosshair control. Not recommended when running a batch of jobs.'};
            else
                app.HelperArea.Value = {'No. Visualization can be conducted later using the visualization tools in the visuals tab.'};
            end
        end

        % Value changed function: UseOverlayCheckBox
        function UseOverlayCheckBoxValueChanged(app, event)
            value = app.UseOverlayCheckBox.Value;
            if value
                app.OverlayPathField.Enable = true; 
                app.OverlayPathButton.Enable = true; 
            else
                app.OverlayPathField.Enable = false; 
                app.OverlayPathButton.Enable = false; 
            end
        end

        % Button pushed function: ViewButton
        function ViewButtonPushed(app, event)
            app.GenCopyButtonPushed();
            path = app.FullImagePathField.Value;
            if exist(path, "file"), view_nii(load_nii(path));
            else, error('nii file does not exist!');
            end
        end

        % Button pushed function: GenCopyButton
        function GenCopyButtonPushed(app, event)
            id = app.SubjectIdField.Value; filename = app.ImageDropDown.Value;
            path = fullfile(app.OutputPathField.Value, id, [filename '_' id '.nii']);
            clipboard('copy', path);
            app.FullImagePathField.Value = path; 
        end

        % Button pushed function: UnderlayPathButton
        function UnderlayPathButtonPushed(app, event)
            path = uigetfile('*.nii', 'Select Image as Underlay'); 
            if path ~= 0, app.UnderlayPathField.Value = path; end
        end

        % Button pushed function: OverlayPathButton
        function OverlayPathButtonPushed(app, event)
            path = uigetfile('*.nii', 'Select Image as Overlay'); 
            if path ~= 0, app.OverlayPathField.Value = path; end
        end

        % Button pushed function: ViewButton_2
        function ViewButton_2Pushed(app, event)
            if app.UseOverlayCheckBox.Value && ~isempty(app.OverlayPathField.Value)
                Visual.init(app.UnderlayPathField.Value, app.OverlayPathField.Value, app.DualDisplayCheckBox.Value);
            elseif ~isempty(app.UnderlayPathField.Value)
                view_nii(load_nii(app.UnderlayPathField.Value));
            end
        end

        % Menu selected function: SaveSettingsMenu
        function SaveSettingsMenuSelected(app, event)
            path = uiputfile('W2MHS_settings.mat', 'Select Folder to Save Settings');
            if path ~= 0, app.saveSettings(path); end
        end

        % Menu selected function: OpenSettingsMenu
        function OpenSettingsMenuSelected(app, event)
            path = uigetfile('*.mat', 'Select Settings File to Load');
            if path ~= 0, app.loadSettings(path); end
        end

        % Menu selected function: SetasDefaultMenu
        function SetasDefaultMenuSelected(app, event)
            if path ~= 0, app.saveSettings("default.mat"); end
            writetable(app.OutputTable.Data, "names.xls");
        end

        % Menu selected function: ResettoDefaultMenu
        function ResettoDefaultMenuSelected(app, event)
            if exist("default.mat", "file"), app.loadSettings("default.mat");
            else
                app.OutputTable.Data = readtable("names.xls"); 
                app.ImageDropDownInit();
            end
        end

        % Menu selected function: SaveBeforeExitMenu
        function SaveBeforeExitMenuSelected(app, event)
            if app.SaveBeforeExitMenu.Checked == "on", app.SaveBeforeExitMenu.Checked = "off";
            else, app.SaveBeforeExitMenu.Checked = "on";
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if app.SaveBeforeExitMenu.Checked == "on", app.SetasDefaultMenuSelected(); end
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.File = uimenu(app.UIFigure);
            app.File.Text = 'File';
            app.SaveSettingsMenu = uimenu(app.File);
            app.SaveSettingsMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveSettingsMenuSelected, true);
            app.SaveSettingsMenu.Tooltip = {'Saves current settings to an external file'};
            app.SaveSettingsMenu.Accelerator = 'S';
            app.SaveSettingsMenu.Text = 'Save Settings';
            app.OpenSettingsMenu = uimenu(app.File);
            app.OpenSettingsMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenSettingsMenuSelected, true);
            app.OpenSettingsMenu.Tooltip = {'Loads current settings from an external file'};
            app.OpenSettingsMenu.Accelerator = 'O';
            app.OpenSettingsMenu.Text = 'Open Settings';
            app.SetasDefaultMenu = uimenu(app.File);
            app.SetasDefaultMenu.MenuSelectedFcn = createCallbackFcn(app, @SetasDefaultMenuSelected, true);
            app.SetasDefaultMenu.Tooltip = {'Sets current settings as default'};
            app.SetasDefaultMenu.Separator = 'on';
            app.SetasDefaultMenu.Accelerator = 'D';
            app.SetasDefaultMenu.Text = 'Set as Default';
            app.ResettoDefaultMenu = uimenu(app.File);
            app.ResettoDefaultMenu.MenuSelectedFcn = createCallbackFcn(app, @ResettoDefaultMenuSelected, true);
            app.ResettoDefaultMenu.Tooltip = {'Sets current settings back to default'};
            app.ResettoDefaultMenu.Accelerator = 'R';
            app.ResettoDefaultMenu.Text = 'Reset to Default';
            app.SaveBeforeExitMenu = uimenu(app.File);
            app.SaveBeforeExitMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveBeforeExitMenuSelected, true);
            app.SaveBeforeExitMenu.Text = 'Save Before Exit';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.SPM12PathField = uieditfield(app.MainTab, 'text');
            app.SPM12PathButton = uibutton(app.MainTab, 'push');
            app.SPM12PathButton.ButtonPushedFcn = createCallbackFcn(app, @SPM12PathButtonPushed, true);
            app.SPM12PathButton.Text = 'SPM12 Path';
            app.OutputPathField = uieditfield(app.MainTab, 'text');
            app.OutputPathButton = uibutton(app.MainTab, 'push');
            app.OutputPathButton.ButtonPushedFcn = createCallbackFcn(app, @OutputPathButtonPushed, true);
            app.OutputPathButton.Text = 'Output Path';
            app.SubjectsListBox = uilistbox(app.MainTab);
            app.SubjectsListBox.Items = {};
            app.SubjectsListBox.Value = {};
            app.AddImagesButton = uibutton(app.MainTab, 'push');
            app.AddImagesButton.ButtonPushedFcn = createCallbackFcn(app, @AddImagesButtonPushed, true);
            app.AddImagesButton.Text = 'Add Images';
            app.RemoveButton = uibutton(app.MainTab, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.RemoveButton.Text = 'Remove';
            app.ClearButton = uibutton(app.MainTab, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.MoveUpButton = uibutton(app.MainTab, 'push');
            app.MoveUpButton.ButtonPushedFcn = createCallbackFcn(app, @MoveUpButtonPushed, true);
            app.MoveUpButton.Text = 'Move Up';
            app.MoveDownButton = uibutton(app.MainTab, 'push');
            app.MoveDownButton.ButtonPushedFcn = createCallbackFcn(app, @MoveDownButtonPushed, true);
            app.MoveDownButton.Text = 'Move Down';
            app.OptionsTab = uitab(app.TabGroup);
            app.OptionsTab.Title = 'Options';
            app.ProceduresPanel = uipanel(app.OptionsTab);
            app.ProceduresPanel.Title = 'Procedures';
            app.VisualizationSwitchLabel = uilabel(app.ProceduresPanel);
            app.VisualizationSwitchLabel.Text = 'Visualization';
            app.VisualSwitch = uiswitch(app.ProceduresPanel, 'slider');
            app.VisualSwitch.Items = {'No', 'Yes'};
            app.VisualSwitch.ValueChangedFcn = createCallbackFcn(app, @VisualSwitchValueChanged, true);
            app.VisualSwitch.Value = 'No';
            app.PreprocSwitch = uiswitch(app.ProceduresPanel, 'slider');
            app.PreprocSwitch.Items = {'No', 'Yes'};
            app.PreprocSwitch.ValueChangedFcn = createCallbackFcn(app, @PreprocSwitchValueChanged, true);
            app.PreprocSwitch.Value = 'No';
            app.PreprocessingSwitch_2Label = uilabel(app.ProceduresPanel);
            app.PreprocessingSwitch_2Label.Text = 'Preprocessing';
            app.HyperparametersPanel = uipanel(app.OptionsTab);
            app.HyperparametersPanel.Title = 'Hyperparameters';
            app.ClassificationBatchSizeSpinnerLabel = uilabel(app.HyperparametersPanel);
            app.ClassificationBatchSizeSpinnerLabel.Text = 'Classification Batch Size';
            app.BatchSizeSpinner = uispinner(app.HyperparametersPanel);
            app.BatchSizeSpinner.Step = 1024;
            app.BatchSizeSpinner.ValueChangingFcn = createCallbackFcn(app, @BatchSizeSpinnerValueChanging, true);
            app.BatchSizeSpinner.Limits = [1 Inf];
            app.BatchSizeSpinner.RoundFractionalValues = 'on';
            app.BatchSizeSpinner.ValueDisplayFormat = '%.0f';
            app.BatchSizeSpinner.Value = 4096;
            app.ProbabilityMapCutoffLabel = uilabel(app.HyperparametersPanel);
            app.ProbabilityMapCutoffLabel.Text = 'Probability Map Cutoff';
            app.PMapCutSpinner = uispinner(app.HyperparametersPanel);
            app.PMapCutSpinner.Step = 0.1;
            app.PMapCutSpinner.ValueChangingFcn = createCallbackFcn(app, @PMapCutSpinnerValueChanging, true);
            app.PMapCutSpinner.Limits = [0 1];
            app.PMapCutSpinner.Value = 0.5;
            app.GrayMatterCleaningDistanceSpinnerLabel = uilabel(app.HyperparametersPanel);
            app.GrayMatterCleaningDistanceSpinnerLabel.Text = 'Gray Matter Cleaning Distance';
            app.CleanThSpinner = uispinner(app.HyperparametersPanel);
            app.CleanThSpinner.Step = 0.5;
            app.CleanThSpinner.ValueChangingFcn = createCallbackFcn(app, @CleanThSpinnerValueChanging, true);
            app.CleanThSpinner.Limits = [0 Inf];
            app.CleanThSpinner.Value = 2.5;
            app.PeriventricularRegionWidthLabel = uilabel(app.HyperparametersPanel);
            app.PeriventricularRegionWidthLabel.Text = 'Periventricular Region Width';
            app.VentDilSpinner = uispinner(app.HyperparametersPanel);
            app.VentDilSpinner.ValueChangingFcn = createCallbackFcn(app, @VentDilSpinnerValueChanging, true);
            app.VentDilSpinner.Limits = [0 10];
            app.VentDilSpinner.RoundFractionalValues = 'on';
            app.VentDilSpinner.ValueDisplayFormat = '%.0f';
            app.VentDilSpinner.Value = 3;
            app.HelperArea = uitextarea(app.OptionsTab);
            app.HelperArea.Editable = 'off';
            app.HelperArea.Value = {'Instructions about an option will be shown here when the option is changed.'};
            app.InputImageTypeIdentifiersPanel = uipanel(app.OptionsTab);
            app.InputImageTypeIdentifiersPanel.Title = 'Input Image Type Identifiers';
            app.T1Label = uilabel(app.InputImageTypeIdentifiersPanel);
            app.T1Label.Text = 'T1:';
            app.T1Field = uieditfield(app.InputImageTypeIdentifiersPanel, 'text');
            app.T1Field.ValueChangingFcn = createCallbackFcn(app, @T1FieldValueChanging, true);
            app.T1Field.Value = 'bravo';
            app.T2EditFieldLabel = uilabel(app.InputImageTypeIdentifiersPanel);
            app.T2EditFieldLabel.Text = 'T2:';
            app.T2Field = uieditfield(app.InputImageTypeIdentifiersPanel, 'text');
            app.T2Field.ValueChangingFcn = createCallbackFcn(app, @T2FieldValueChanging, true);
            app.T2Field.Value = 'flair';
            app.OutputsTab = uitab(app.TabGroup);
            app.OutputsTab.Title = 'Outputs';
            app.OutputTable = uitable(app.OutputsTab);
            app.OutputTable.ColumnName = {'Keep'; 'Filename'; 'Ext'; 'Name'; 'Description'};
            app.OutputTable.RowName = {''};
            app.OutputTable.ColumnEditable = [true true false false false];
            app.VisualsTab = uitab(app.TabGroup);
            app.VisualsTab.Title = 'Visuals';
            app.QuickNIfTIImageViewerPathGeneratorPanel = uipanel(app.VisualsTab);
            app.QuickNIfTIImageViewerPathGeneratorPanel.Title = 'Quick NIfTI Image Viewer & Path Generator';
            app.ImageDropDownLabel = uilabel(app.QuickNIfTIImageViewerPathGeneratorPanel);
            app.ImageDropDownLabel.Text = 'Image';
            app.ImageDropDown = uidropdown(app.QuickNIfTIImageViewerPathGeneratorPanel);
            app.ImageDropDown.Items = {};
            app.ImageDropDown.Value = {};
            app.ViewButton = uibutton(app.QuickNIfTIImageViewerPathGeneratorPanel, 'push');
            app.ViewButton.ButtonPushedFcn = createCallbackFcn(app, @ViewButtonPushed, true);
            app.ViewButton.Text = 'View';
            app.SubjectIdEditFieldLabel = uilabel(app.QuickNIfTIImageViewerPathGeneratorPanel);
            app.SubjectIdEditFieldLabel.Text = 'Subject Id';
            app.SubjectIdField = uieditfield(app.QuickNIfTIImageViewerPathGeneratorPanel, 'text');
            app.FullImagePathEditFieldLabel = uilabel(app.QuickNIfTIImageViewerPathGeneratorPanel);
            app.FullImagePathEditFieldLabel.Text = 'Full Image Path';
            app.FullImagePathField = uieditfield(app.QuickNIfTIImageViewerPathGeneratorPanel, 'text');
            app.FullImagePathField.Editable = 'off';
            app.GenCopyButton = uibutton(app.QuickNIfTIImageViewerPathGeneratorPanel, 'push');
            app.GenCopyButton.ButtonPushedFcn = createCallbackFcn(app, @GenCopyButtonPushed, true);
            app.GenCopyButton.Text = 'Gen & Copy';
            app.AdvancedNIfTIImageViewerPanel = uipanel(app.VisualsTab);
            app.AdvancedNIfTIImageViewerPanel.Title = 'Advanced NIfTI Image Viewer';
            app.UnderlayPathField = uieditfield(app.AdvancedNIfTIImageViewerPanel, 'text');
            app.OverlayPathField = uieditfield(app.AdvancedNIfTIImageViewerPanel, 'text');
            app.ViewButton_2 = uibutton(app.AdvancedNIfTIImageViewerPanel, 'push');
            app.ViewButton_2.ButtonPushedFcn = createCallbackFcn(app, @ViewButton_2Pushed, true);
            app.ViewButton_2.Text = 'View';
            app.UseOverlayCheckBox = uicheckbox(app.AdvancedNIfTIImageViewerPanel);
            app.UseOverlayCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseOverlayCheckBoxValueChanged, true);
            app.UseOverlayCheckBox.Text = 'Use Overlay';
            app.UseOverlayCheckBox.Value = true;
            app.DualDisplayCheckBox = uicheckbox(app.AdvancedNIfTIImageViewerPanel);
            app.DualDisplayCheckBox.Text = 'Show Underlay in A Separate Window';
            app.DualDisplayCheckBox.Value = true;
            app.UnderlayPathButton = uibutton(app.AdvancedNIfTIImageViewerPanel, 'push');
            app.UnderlayPathButton.ButtonPushedFcn = createCallbackFcn(app, @UnderlayPathButtonPushed, true);
            app.UnderlayPathButton.Text = 'Underlay Path';
            app.OverlayPathButton = uibutton(app.AdvancedNIfTIImageViewerPanel, 'push');
            app.OverlayPathButton.ButtonPushedFcn = createCallbackFcn(app, @OverlayPathButtonPushed, true);
            app.OverlayPathButton.Text = 'Overlay Path';
            app.BrainImage = uiimage(app.UIFigure);
            app.BrainImage.ImageSource = 'brain.jpg';
            app.WiscImage = uiimage(app.UIFigure);
            app.WiscImage.ImageSource = 'uw.png';
            app.ShadowLabel = uilabel(app.UIFigure);
            app.ShadowLabel.Text = '';
            app.TitleLabel = uilabel(app.UIFigure);
            app.TitleLabel.Text = {'Wisconsin White Matter Hyperintensity '; 'Segmentation and Quantification'};
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.VersionLabel = uilabel(app.UIFigure);
            app.VersionLabel.Text = 'v2019.3';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = w2mhs

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end